classdef findRoots < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        methodsDropDownLabel         matlab.ui.control.Label
        methodsDropDown              matlab.ui.control.DropDown
        EquationEditFieldLabel       matlab.ui.control.Label
        EquationEditField            matlab.ui.control.EditField
        initialpoint1EditFieldLabel  matlab.ui.control.Label
        initialpoint1EditField       matlab.ui.control.NumericEditField
        initialpoint2EditFieldLabel  matlab.ui.control.Label
        initialpoint2EditField       matlab.ui.control.NumericEditField
        precisionLabel               matlab.ui.control.Label
        precisionEditField           matlab.ui.control.NumericEditField
        maxiterationsEditFieldLabel  matlab.ui.control.Label
        maxiterationsEditField       matlab.ui.control.NumericEditField
        fastButton                   matlab.ui.control.Button
        nextButton                   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        Label                        matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        ReadButton                   matlab.ui.control.Button
        setEquationButton            matlab.ui.control.Button
    end


    properties (Access = public)
        methodName = "Bisection"; % Description
        counter = 1;
        equation
        equationStr
        x1=0;
        x2 = 0;
        X1Arr 
        X2Arr 
        maxIter
        precision
        outArr ={['kj' 'ttt']};
    end

    methods (Access = private)
    
        function [] = drawBisection(app)           
            Y = -10:0.1:10;
            
            if app.counter <= numel(app.X1Arr)
                xLCell = app.X1Arr(app.counter);
            else
                xLCell = app.X1Arr(numel(app.X1Arr));
            end
            xLString =strjoin(xLCell);
            xL =str2double(xLString) ;
            X = xL * ones(size(Y));
            
            if app.counter <= numel(app.X2Arr)
                xUCell = app.X2Arr(app.counter);
            else
                xUCell = app.X2Arr(numel(app.X1Arr));
            end
            xUString =strjoin(xUCell);
            xU =str2double(xUString) ;
            X2 = xU * ones(size(Y));
            
            x = linspace(app.x1,app.x2);
            plot(app.UIAxes,x,app.equation(x),X,Y,X2,Y);
            app.UIAxes.YAxis.Limits = [-10 50];
        end
 
        function [] = drawFalse(app)
            cla(app.UIAxes);
            hold(app.UIAxes,'on');
            
            if app.counter <= numel(app.X1Arr)
                xLCell = app.X1Arr(app.counter);
            else
                xLCell = app.X1Arr(numel(app.X1Arr));
            end
            xLString =strjoin(xLCell);
            xL =str2double(xLString) ;
            
            
            if app.counter <= numel(app.X2Arr)
                xUCell = app.X2Arr(app.counter);
            else
                xUCell = app.X2Arr(numel(app.X1Arr));
            end
            xUString =strjoin(xUCell);
            xU =str2double(xUString) ;
            
            Y = -10:0.1:10;
            X = xL * ones(size(Y));
            X2 = xU * ones(size(Y));
            
            x = linspace(app.x1,app.x2);
            plot(app.UIAxes,x,app.equation(x),X,Y,X2,Y);
            plot(app.UIAxes,[xL xU],[app.equation(xL) app.equation(xU)]);
            hold(app.UIAxes,'off');
            app.UIAxes.YAxis.Limits = [-10 50];
        end
    
        function [] = drawFixed(app)
            syms g(x)
            g(x) = app.equation+x;
            syms g2(x)
            g2(x) = x;
            x = linspace(-20,20);
            app.UIAxes.YAxis.Limits = [-10 50];
            plot(app.UIAxes,x,g(x),x,g2(x));
        end
    
        function [] = drawNewton(app)
            drawSecant(app);
%             dif = diff(app.equation,x); 
%             x = linspace(-20,20);
%             plot(app.UIAxes,x,dif(x));       
        end
        
        function [] = drawSecant(app)
           x = -10 : 0.1:10;
           df =  diff(str2sym(app.equationStr));
           dFunc = matlabFunction(df);
           app.UIAxes.YAxis.Limits = [-10 50];
%            app.UIAxes.YAxis.TickLength = [0.02 0.035];
           plot(app.UIAxes,x,dFunc(x));
            
% %             syms x;
% %             dif = diff(app.equation);
% %             d = diff(x);
% %             f = d./dif;
% %             x = linspace(-1,1);
%               x = 0.1 : 0.1:1;
%             %f = eval(['@(x)' char(diff(app.equation(x)))]);
%            df =  diff(str2sym(app.equationStr));
%            disp(df);
% %             df = diff(app.equation(x));
% %             f = char(df);
% %             f=strcat('@(x)',f);
% %             ff = str2fun(f);
% %             
% %             
%             f = char(df);
%             dff =str2sym(f);
%             disp(dff);
%             f=strcat('@(x)',f);
%             
%              ff = str2func(f);
% %             plot(app.UIAxes,x,dff(x));
% plot(app.UIAxes,x,ff(x));        
        end
        function [] = drawAllRoots(app)
            %drawSecant(app);
%             dif = diff(app.equation,x); 
%             %x = linspace(-20,20);
%             plot(app.UIAxes,x(1:end-1),dif(x));     
        end
    
        function [] = drawVieta(app)
            drawSecant(app)
%             dif = diff(app.equation,x); 
%             x = linspace(-20,20);
%             plot(app.UIAxes,x,dif(x));    
        end
    
        function [] = draw(app)
             switch app.methodName
                case "Bisection"
                    drawBisection(app);
                case "Newton-Raphson"
                    drawNewton(app);
                case "Fixed point"
                    drawFixed(app);
                case "Secant"
                    drawSecant(app);
                case "False-position"
                    drawFalse(app);
                case "Bierge Vieta"
                    drawVieta(app);
                case "AllRoots"
                    drawAllRoots(app);
                otherwise
                     %disp('other value')
            end
        end
    
    end


    methods (Access = private)

        % Value changed function: methodsDropDown
        function methodsDropDownValueChanged(app, event)
            value = app.methodsDropDown.Value;
            app.methodName = value;
            %app.TextArea.Value =app.methodName;
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            arrSize = numel(app.outArr);
            outputString = "";
            if app.counter <= arrSize
                if app.counter == 1
                    outputString = app.outArr(app.counter);
                else
                    oldOutput = strjoin(app.TextArea.Value,'\n');
                    lineString = "" + newline ;
                    newString = strjoin( app.outArr(app.counter) );
                    outputString = oldOutput + lineString ;
                    outputString = outputString + newString;
                end
                draw(app);
                app.counter = app.counter + 1;
                app.TextArea.Value = outputString;                                   
            end
        end

        % Button pushed function: fastButton
        function fastButtonPushed(app, event)
            app.counter = numel(app.outArr) + 1;
            disp(app.outArr);
            app.TextArea.Value = app.outArr;
            draw(app);
        end

        % Button pushed function: setEquationButton
        function setEquationButtonPushed(app, event)
            app.x1 = app.initialpoint1EditField.Value;
            app.x2 = app.initialpoint2EditField.Value;
            app.maxIter = app.maxiterationsEditField.Value;
            app.precision = app.precisionEditField.Value;
            
            app.equationStr = app.EquationEditField.Value;
            app.equation = functionFromString(app.equationStr);
            
            app.counter = 1;
            app.TextArea.Value = "";
            cla(app.UIAxes);
            switch app.methodName
                case "Bisection"
                    [app.outArr,app.X1Arr,app.X2Arr] = myBisectionAlgorithm(app.equation,app.x1,app.x2,app.maxIter,app.precision);
                case "Newton-Raphson"
                    [app.outArr,app.X1Arr,app.X2Arr] =  Newton_Raphson(app.x1,app.equation,app.maxIter,app.precision);
                case "Fixed point"
                    [app.outArr,app.X1Arr,app.X2Arr] = FixedPoint(app.x1,app.equation,app.maxIter,app.precision);
                case "Secant"
                    [app.outArr,app.X1Arr,app.X2Arr] = Secant(app.x1,app.x2,app.equation,app.maxIter,app.precision);
                case "False-position"
                    [app.outArr,app.X1Arr,app.X2Arr] = myFalsePosition(app.equation,app.x1,app.x2,app.maxIter,app.precision);
                case "Bierge Vieta"
                    coefArr = getCoef(app.equationStr);
                    [app.outArr] = birgeVietaCaller(coefArr,app.x1,app.maxIter,app.precision);
                case "AllRoots"
                    [app.outArr] = getAllRoots(app.equation,app.maxIter,app.precision);
                otherwise
                     %disp('other value')
            end            
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            [app.methodName,app.equation,app.x1,app.x2,app.precision,app.maxIter] = fileReader("input.txt");
            app.methodsDropDown.Value = app.methodName;
            app.EquationEditField.Value = app.equation;
            app.initialpoint1EditField.Value = app.x1;
            app.initialpoint2EditField.Value = app.x2;
            app.precisionEditField.Value = app.precision;
            app.maxiterationsEditField.Value = app.maxIter;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.methodsDropDownLabel = uilabel(app.UIFigure);
            app.methodsDropDownLabel.Text = 'methods';
            app.methodsDropDown = uidropdown(app.UIFigure);
            app.methodsDropDown.Items = {'Bisection', 'False-position', 'Fixed point', 'Newton-Raphson', 'Secant', 'Bierge Vieta', 'AllRoots'};
            app.methodsDropDown.ValueChangedFcn = createCallbackFcn(app, @methodsDropDownValueChanged, true);
            app.methodsDropDown.Value = 'Bisection';
            app.EquationEditFieldLabel = uilabel(app.UIFigure);
            app.EquationEditFieldLabel.Text = 'Equation';
            app.EquationEditField = uieditfield(app.UIFigure, 'text');
            app.initialpoint1EditFieldLabel = uilabel(app.UIFigure);
            app.initialpoint1EditFieldLabel.Text = 'initial point 1';
            app.initialpoint1EditField = uieditfield(app.UIFigure, 'numeric');
            app.initialpoint2EditFieldLabel = uilabel(app.UIFigure);
            app.initialpoint2EditFieldLabel.Text = 'initial point 2';
            app.initialpoint2EditField = uieditfield(app.UIFigure, 'numeric');
            app.precisionLabel = uilabel(app.UIFigure);
            app.precisionLabel.Text = 'precision';
            app.precisionEditField = uieditfield(app.UIFigure, 'numeric');
            app.precisionEditField.Value = 0.0001;
            app.maxiterationsEditFieldLabel = uilabel(app.UIFigure);
            app.maxiterationsEditFieldLabel.Text = 'max iterations';
            app.maxiterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxiterationsEditField.Value = 50;
            app.fastButton = uibutton(app.UIFigure, 'push');
            app.fastButton.ButtonPushedFcn = createCallbackFcn(app, @fastButtonPushed, true);
            app.fastButton.Text = 'fast';
            app.nextButton = uibutton(app.UIFigure, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.setEquationButton = uibutton(app.UIFigure, 'push');
            app.setEquationButton.ButtonPushedFcn = createCallbackFcn(app, @setEquationButtonPushed, true);
            app.setEquationButton.Text = 'set Equation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = findRoots

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end